classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BerkeleyEarthGlobalTemperatureEstimateUIFigure  matlab.ui.Figure
        DefaultSettingsButton       matlab.ui.control.Button
        AveragingWindowSlider       matlab.ui.control.Slider
        AveragingWindowSliderLabel  matlab.ui.control.Label
        StopKnob                    matlab.ui.control.Knob
        StopKnobLabel               matlab.ui.control.Label
        StartKnob                   matlab.ui.control.Knob
        StartKnobLabel              matlab.ui.control.Label
        DataSourceButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        MonthlyAverageButton        matlab.ui.control.RadioButton
        MonthlyButton               matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Temperatures % global temperature in C
        TimePoints % measurement times (in years)
        ShowAverage %plot average
        ShowMonthly %plot monthly
        Averages    %Averaged data
        StartYear   %Start year
        StopYear    %Stop year
        WindowSize  %window     
    end
    
    methods (Access = private)
        
        function plotData(app)
            xlim(app.UIAxes,[app.StartYear,app.StopYear]);
            xlim(app.UIAxes,'manual');
            ylim(app.UIAxes,[-1.5,1.5]);
            ylim(app.UIAxes,'manual');
            grid(app.UIAxes,'on');
            if app.ShowMonthly
                plot(app.UIAxes,app.TimePoints,app.Temperatures);
                hold(app.UIAxes,'on');
            end
            if app.ShowAverage
                half_window=app.WindowSize/2;
                Start=floor(half_window)+1;
                Stop=length(app.TimePoints)-(ceil(half_window)-1);
                inside_range=Start:Stop;
                PartialTimePoints=app.TimePoints(inside_range);
                PartialAverages=app.Averages(inside_range);
                plot(app.UIAxes,PartialTimePoints,PartialAverages,'r','LineWidth',2)
                plot(app.UIAxes,app.TimePoints,app.Averages,'r','LineWidth',2);
            end
            hold(app.UIAxes,'off');
        end
        
        function AverageData(app)
            app.Averages=movmean(app.Temperatures,app.WindowSize);
        end
        
        function setDefault(app)
            app.AveragingWindowSlider.Value=app.AveragingWindowSlider.Limits(2)/2;
            app.BothButton.Value=true;
            app.MonthlyAverageButton.Value=false;
            app.MonthlyButton.Value=false;
            app.StartKnob.Value=app.StartKnob.Limits(1);
            app.StopKnob.Value=app.StopKnob.Limits(2);
            % set properties
            app.WindowSize=app.AveragingWindowSlider.Value;
            app.ShowAverage=app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly=app.MonthlyButton.Value || app.BothButton.Value;
            app.StopYear=app.StopKnob.Value;
            app.StartYear=app.StartKnob.Value;
            %plot data
            app.AverageData();
            app.plotData();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load GlobalTemp.mat data;
            app.Temperatures=data(:,2);
            app.TimePoints=data(:,1);
%             app.ShowAverage=app.MonthlyAverageButton.Value || app.BothButton.Value;
%             app.ShowMonthly=app.MonthlyButton.Value || app.BothButton.Value;
%             app.StartYear=app.StartKnob.Value;
%             app.StopYear=app.StopKnob.Value;
%             app.WindowSize=app.AveragingWindowSlider.Value;
%             app.AverageData();
%             app.plotData;
              app.setDefault();
        end

        % Close request function: 
        % BerkeleyEarthGlobalTemperatureEstimateUIFigure
        function BerkeleyEarthGlobalTemperatureEstimateUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            %{
            method 1
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            switch selectedButton
                case app.MonthlyButton
                    fprintf("Monthly pushed\n");
                case app.MonthlyAverageButton
                    fprintf("Monthly average pushed\n");
                otherwise
                    fprintf("Both pushed\n");
            end
            %}
            
            %{
            method 2
            if app.MonthlyButton.Value
                fprintf("Monthly pushed\n");
            else if app.MonthlyAverageButton.Value
                fprintf("Monthly average pushed\n");
            else if app.BothButton.Value
                fprintf("Both pushed\n");
            end
            %}
            
            %{
            % method 3
            switch event.NewValue
                if app.ShowMonthly
                    
                end
            end 
            %}
            app.ShowAverage=app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly=app.MonthlyButton.Value || app.BothButton.Value;
            app.plotData;
        end

        % Value changing function: StartKnob
        function StartKnobValueChanging(app, event)
            app.StartYear = event.Value;
            if app.StartYear > app.StopYear-1
                app.StopYear=min([app.StartYear+1,app.StopKnob.Limits(2)]);
                app.StopKnob.Value=app.StopYear;
                app.StartYear=app.StopYear;
                app.StartKnob.Value=app.StartYear;
            end
            app.plotData;
        end

        % Value changing function: StopKnob
        function StopKnobValueChanging(app, event)
            app.StopYear = event.Value;
            if app.StopYear < app.StartYear+1
                app.StartYear=max([app.StopYear-1,app.StartKnob.Limits(1)]);
                app.StartKnob.Value=app.StartYear;
                app.StopYear=app.StartYear+1;
                app.StopKnob.Value=app.StopYear;
            end
            app.plotData;
        end

        % Value changing function: AveragingWindowSlider
        function AveragingWindowSliderValueChanging(app, event)
            app.WindowSize = event.Value;
            app.AverageData();
            app.plotData;
        end

        % Button pushed function: DefaultSettingsButton
        function DefaultSettingsButtonPushed(app, event)
            app.setDefault();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure = uifigure('Visible', 'off');
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.Name = 'Berkeley Earth Global Temperature Estimate';
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.CloseRequestFcn = createCallbackFcn(app, @BerkeleyEarthGlobalTemperatureEstimateUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            title(app.UIAxes, 'Global Temperature Anomoly')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'C(relative to 1950-1980 mean)')
            zlabel(app.UIAxes, 'Z')
            app.DataSourceButtonGroup = uibuttongroup(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source';
            app.MonthlyButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyButton.Text = 'Monthly';
            app.MonthlyAverageButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyAverageButton.Text = 'Monthly Average';
            app.BothButton = uiradiobutton(app.DataSourceButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.StartKnobLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.StartKnobLabel.Text = 'Start';
            app.StartKnob = uiknob(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'continuous');
            app.StartKnob.Limits = [1840 2020];
            app.StartKnob.MajorTicks = [1850 1910 1960 2020];
            app.StartKnob.ValueChangingFcn = createCallbackFcn(app, @StartKnobValueChanging, true);
            app.StartKnob.Value = 1840;
            app.StopKnobLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.StopKnobLabel.Text = 'Stop';
            app.StopKnob = uiknob(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'continuous');
            app.StopKnob.Limits = [1840 2020];
            app.StopKnob.MajorTicks = [1850 1910 1960 2020];
            app.StopKnob.ValueChangingFcn = createCallbackFcn(app, @StopKnobValueChanging, true);
            app.StopKnob.Value = 2020;
            app.AveragingWindowSliderLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.AveragingWindowSliderLabel.Text = 'Averaging Window';
            app.AveragingWindowSlider = uislider(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.AveragingWindowSlider.Limits = [6 120];
            app.AveragingWindowSlider.MajorTicks = [6 30 60 90 120];
            app.AveragingWindowSlider.ValueChangingFcn = createCallbackFcn(app, @AveragingWindowSliderValueChanging, true);
            app.AveragingWindowSlider.Value = 60;
            app.DefaultSettingsButton = uibutton(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'push');
            app.DefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSettingsButtonPushed, true);
            app.DefaultSettingsButton.Text = 'Default Settings';
            % Show the figure after all components are created
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BerkeleyEarthGlobalTemperatureEstimateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure)
        end
    end
end