classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BerkeleyEarthGlobalTemperatureEstimateUIFigure  matlab.ui.Figure
        DefaultSettingsButton       matlab.ui.control.Button
        AveragingWindowSlider       matlab.ui.control.Slider
        AveragingWindowSliderLabel  matlab.ui.control.Label
        StopKnob                    matlab.ui.control.Knob
        StopKnobLabel               matlab.ui.control.Label
        StartKnob                   matlab.ui.control.Knob
        StartKnobLabel              matlab.ui.control.Label
        DataSourceButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        MonthlyAverageButton        matlab.ui.control.RadioButton
        MonthlyButton               matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Temperatures % global temperature in C
        TimePoints % measurement times (in years)
        ShowAverage %plot average
        ShowMonthly %plot monthly
        Averages    %Averaged data
    end
    
    methods (Access = private)
        
        function plotData(app)
            if app.ShowMonthly
                plot(app.UIAxes,app.TimePoints,app.Temperatures);
                hold(app.UIAxes,'on');
            end
            if app.ShowAverage
                plot(app.UIAxes,app.TimePoints,app.Averages,'r','LineWidth',2);
            end
            hold(app.UIAxes,'off');
        end
        
        function AverageData(app)
            app.Averages=movmean(app.Temperatures,30);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load GlobalTemp.mat data;
            app.Temperatures=data(:,2);
            app.TimePoints=data(:,1);
            app.ShowAverage=app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly=app.MonthlyButton.Value || app.BothButton.Value;
            app.AverageData();
            app.plotData;
        end

        % Close request function: 
        % BerkeleyEarthGlobalTemperatureEstimateUIFigure
        function BerkeleyEarthGlobalTemperatureEstimateUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            %{
            method 1
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            switch selectedButton
                case app.MonthlyButton
                    fprintf("Monthly pushed\n");
                case app.MonthlyAverageButton
                    fprintf("Monthly average pushed\n");
                otherwise
                    fprintf("Both pushed\n");
            end
            %}
            
            %{
            method 2
            if app.MonthlyButton.Value
                fprintf("Monthly pushed\n");
            else if app.MonthlyAverageButton.Value
                fprintf("Monthly average pushed\n");
            else if app.BothButton.Value
                fprintf("Both pushed\n");
            end
            %}
            
            %{
            % method 3
            switch event.NewValue
                if app.ShowMonthly
                    
                end
            end 
            %}
            app.ShowAverage=app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly=app.MonthlyButton.Value || app.BothButton.Value;
            app.plotData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure = uifigure('Visible', 'off');
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.Name = 'Berkeley Earth Global Temperature Estimate';
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.CloseRequestFcn = createCallbackFcn(app, @BerkeleyEarthGlobalTemperatureEstimateUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            title(app.UIAxes, 'Global Temperature Anomoly')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'C(relative to 1950-1980 mean)')
            zlabel(app.UIAxes, 'Z')
            app.DataSourceButtonGroup = uibuttongroup(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source';
            app.MonthlyButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyButton.Text = 'Monthly';
            app.MonthlyAverageButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyAverageButton.Text = 'Monthly Average';
            app.BothButton = uiradiobutton(app.DataSourceButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.StartKnobLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.StartKnobLabel.Text = 'Start';
            app.StartKnob = uiknob(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'continuous');
            app.StartKnob.Limits = [1840 2020];
            app.StartKnob.MajorTicks = [1850 1910 1960 2020];
            app.StartKnob.Value = 1840;
            app.StopKnobLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.StopKnobLabel.Text = 'Stop';
            app.StopKnob = uiknob(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'continuous');
            app.StopKnob.Limits = [1840 2020];
            app.StopKnob.MajorTicks = [1850 1910 1960 2020];
            app.StopKnob.Value = 2020;
            app.AveragingWindowSliderLabel = uilabel(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.AveragingWindowSliderLabel.Text = 'Averaging Window';
            app.AveragingWindowSlider = uislider(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure);
            app.AveragingWindowSlider.Limits = [6 120];
            app.AveragingWindowSlider.MajorTicks = [6 30 60 90 120];
            app.AveragingWindowSlider.Value = 60;
            app.DefaultSettingsButton = uibutton(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure, 'push');
            app.DefaultSettingsButton.Text = 'Default Settings';
            % Show the figure after all components are created
            app.BerkeleyEarthGlobalTemperatureEstimateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BerkeleyEarthGlobalTemperatureEstimateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BerkeleyEarthGlobalTemperatureEstimateUIFigure)
        end
    end
end