classdef Covid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19tracerUIFigure       matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool2                   matlab.ui.container.toolbar.PushTool
        PushTool                    matlab.ui.container.toolbar.PushTool
        DataSourceButton            matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        ClearButton                 matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        ToDatePicker                matlab.ui.control.DatePicker
        ToDatePickerLabel           matlab.ui.control.Label
        FromDatePicker              matlab.ui.control.DatePicker
        FromDatePickerLabel         matlab.ui.control.Label
        DefaultSettingsButton       matlab.ui.control.StateButton
        StateorRegionListBox        matlab.ui.control.ListBox
        StateorRegionLabel          matlab.ui.control.Label
        CountryListBox              matlab.ui.control.ListBox
        CountryListBoxLabel         matlab.ui.control.Label
        OptionButtonGroup           matlab.ui.container.ButtonGroup
        DailyButton                 matlab.ui.control.RadioButton
        CumulativeButton            matlab.ui.control.RadioButton
        AveragednumberofdaysSlider  matlab.ui.control.Slider
        AveragednumberofdaysSliderLabel  matlab.ui.control.Label
        DatatoPlotButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        CasesButton                 matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CovidData         % cell array - given data
        NumberOfCountries % Number of countries
        NumberOfStates    % Number of States
        ListOfStates       % List of Countries
        ListOfCountry
        ListOfCountries   % List of Countries
        SelectedCountry   % Selected country
        SelectedState     % Selected state
        SelectedCountryReign % Reign of selected States
        ShowCases
        ShowDeaths
        ShowCumulative
        AverageCumulativeCases
        TimePoints
        AverageDailyDeaths
        AverageCumulativeDeaths
        ShowDaily
        AverageDailyCases
        ShowBoth
        StartDate
        EndDate
        AverageOfDay
        GlobalDailyCases
        GlobalDailyDeaths
        GlobalCumulativeCases
        GlobalCumulativeDeaths
        Dates
        ListOfCountryNames
        StateMap
        CountryorStateorRegionToFind
        
    end
    
    methods (Access = private)
        
        function GetStates(app)
            app.NumberOfCountries=length(unique(app.CovidData(:,1)));
            app.NumberOfStates=length(app.CovidData(:,1));
            temp_states=app.CovidData(:,1:2);
            app.ListOfStates=strings(app.NumberOfStates,2);
            for i=1:app.NumberOfStates
                app.ListOfStates(i,1)=string(temp_states(i,1)); 
                app.ListOfStates(i,2)=string(temp_states(i,2));
            end
            app.ListOfCountry=["Global";unique(app.ListOfStates(:,1))];
            app.ListOfStates=[["Global","All"];app.ListOfStates];
        end

        function title = MakeTitle(app)
            if app.ShowCumulative
                ChartFrequency = 'Cumulative';
            elseif app.ShowDaily
                ChartFrequency = 'Daily';
            end
            
            if (app.ShowCases && ~app.ShowDeaths)
                ChartDataType = 'Cases';
            elseif (app.ShowDeaths && ~app.ShowCases)
                ChartDataType = 'Deaths';
            else
                ChartDataType = 'Cases and Deaths';
            end
            
            if isequal(app.SelectedCountry, 'Global')
                ChartName = 'Globally';
            elseif isequal(app.SelectedState, 'All')
                ChartName = [ 'in ', app.SelectedCountry ];
            else
                ChartName = [ 'in ', app.SelectedState ];
            end

            if (app.AverageOfDay > 1)
                ChartDaysMean = [ ' (', num2str(app.AverageOfDay), '-day mean)' ];
            else
                ChartDaysMean = '';
            end

            title = [ ChartFrequency, ' Number of ', ChartDataType, ' ', ChartName, ChartDaysMean ];               
        end
        
        function DefaultSettings(app)
            app.CountryListBox.Items=app.ListOfCountry;  
            app.CountryListBox.Value="Global";
            app.SelectedCountryReign=["All"];
            app.StateorRegionListBox.Items=app.SelectedCountryReign;
            
            app.EditField.FontColor = [ 0.65 0.65 0.65 ];
            app.EditField.BackgroundColor = 'white';
            app.EditField.Placeholder = 'Enter Country or State/Region Name...';
            app.EditField.FontWeight = 'bold';

            app.CumulativeButton.Value = true;
            app.CasesButton.Value = true;
            app.DailyButton.Value = false;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            app.AveragednumberofdaysSlider.Value = 1;
            
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.FromDatePicker.Limits = [ datetime(app.StartDate, 'ConvertFrom', 'datenum'),datetime(app.EndDate, 'ConvertFrom', 'datenum') ];
            app.ToDatePicker.Limits = app.FromDatePicker.Limits;
            app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
            app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
            
            % Set my properties
            app.SelectedCountry = app.CountryListBox.Value;
            app.SelectedState = app.StateorRegionListBox.Value;
            app.AverageOfDay = app.AveragednumberofdaysSlider.Value;
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            
            app.PlotData();

        end

        function LabelDates(app)
            app.Dates = app.CovidData(1,3:end);
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.TimePoints = linspace(app.StartDate , app.EndDate, length(app.Dates));
        end
        
        function GenerateData(app)
            app.ListOfCountryNames = unique(app.CovidData(2:end,1));
            app.NumberOfCountries = length(app.ListOfCountryNames);
            app.NumberOfStates = app.NumberOfCountries - sum(cellfun('isempty', app.CovidData(2:end,2)));
            keySet = cell(1,app.NumberOfStates);
            valueSet = cell(1,app.NumberOfStates);
            index = 1;
            for ii = 1 : app.NumberOfCountries
                nameOfCountry = app.ListOfCountryNames{ii};
                [~,indexOfCountryIncludingGlobal] = ismember(nameOfCountry, app.CovidData(:,1));
                app.ListOfCountries{ii} = CovidCountryData(app.CovidData, nameOfCountry, indexOfCountryIncludingGlobal);
                if ~isempty(app.ListOfCountries{ii}.ListOfStates)
                    for jj = 1 : length(app.ListOfCountries{ii}.ListOfStates)
                        keySet{index} = app.ListOfCountries{ii}.ListOfStates{jj}.Name;
                        valueSet{index} = app.ListOfCountries{ii}.Name;
                        index = index + 1;
                    end
                end
            end
            app.StateMap = containers.Map(keySet, valueSet);
            
            app.GlobalCumulativeCases = zeros(1,length(app.Dates));   % pre-allocation
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeCases = app.GlobalCumulativeCases + app.ListOfCountries{i}.CumulativeCases;
            end
            app.GlobalCumulativeDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeDeaths = app.GlobalCumulativeDeaths + app.ListOfCountries{i}.CumulativeDeaths;
            end
            app.GlobalDailyCases = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyCases = app.GlobalDailyCases + app.ListOfCountries{i}.DailyCases;
            end
            app.GlobalDailyDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyDeaths = app.GlobalDailyDeaths + app.ListOfCountries{i}.DailyDeaths;
            end
        end

        function GetCountryStates(app)   
            app.SelectedCountryReign=["All"];
            for i=1:app.NumberOfStates
                if(app.SelectedCountry==app.ListOfStates(i,1) && app.ListOfStates(i,2)~="")
                        app.SelectedCountryReign=[app.SelectedCountryReign,app.ListOfStates(i,2)];
                end
            end
        end
        
        function ComputeAverages(app)
            if (app.AverageOfDay > 1)
                if isequal(app.SelectedCountry, 'Global')
                    app.AverageDailyCases = movmean(app.GlobalDailyCases, app.AverageOfDay);
                    app.AverageDailyDeaths = movmean(app.GlobalDailyDeaths, app.AverageOfDay);
                    app.AverageCumulativeCases = movmean(app.GlobalCumulativeCases, app.AverageOfDay);
                    app.AverageCumulativeDeaths = movmean(app.GlobalCumulativeDeaths, app.AverageOfDay);
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.SelectedCountry).IndexOfCountryExcludingGlobal;
                    if isequal(app.SelectedState, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = movmean(Country.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(Country.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(Country.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(Country.CumulativeDeaths, app.AverageOfDay);
                    else
                        [~,index] = ismember(app.SelectedState, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = movmean(State.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(State.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(State.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(State.CumulativeDeaths, app.AverageOfDay);
                    end
                end
            else
                if isequal(app.SelectedCountry, 'Global')
                    app.AverageDailyCases = app.GlobalDailyCases;
                    app.AverageDailyDeaths = app.GlobalDailyDeaths;
                    app.AverageCumulativeCases = app.GlobalCumulativeCases;
                    app.AverageCumulativeDeaths = app.GlobalCumulativeDeaths;
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.SelectedCountry).IndexOfCountryExcludingGlobal;
                    if isequal(app.SelectedState, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = Country.DailyCases;
                        app.AverageDailyDeaths = Country.DailyDeaths;
                        app.AverageCumulativeCases = Country.CumulativeCases;
                        app.AverageCumulativeDeaths = Country.CumulativeDeaths;
                    else
                        [~,index] = ismember(app.SelectedState, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = State.DailyCases;
                        app.AverageDailyDeaths = State.DailyDeaths;
                        app.AverageCumulativeCases = State.CumulativeCases;
                        app.AverageCumulativeDeaths = State.CumulativeDeaths;
                    end
                end
            end
        end

        function PlotData(app)
            cla(app.UIAxes, 'reset');
            app.ComputeAverages();
            if ((app.ShowCases && ~app.ShowDeaths) && app.ShowCumulative)
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                xlabel(app.UIAxes, 'Dates');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowCases && ~app.ShowDeaths) && app.ShowDaily)
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowCumulative)
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 1.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowDaily)
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'red', 'LineWidth', 1.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif (app.ShowBoth && app.ShowCumulative)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');
                
                if (max(app.AverageCumulativeDeaths) < 20)
                    app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            elseif (app.ShowBoth && app.ShowDaily)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'red', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');

                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            end
            
            set(app.UIAxes, 'FontName', 'Segoe UI', 'FontSize', 14);
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'off');
            
            % format date timepoints on x axis
            datetick(app.UIAxes, 'x', 'mm/dd');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            %xtickangle(app.UIAxes, 30);
            xlabel(app.UIAxes, 'Dates');
            
            % turn off scientic notations on y axes
            if app.ShowBoth
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
            else
                app.UIAxes.YAxis.Exponent = 0;
            end
            ytickformat(app.UIAxes, '%.0f');
            
            title(app.UIAxes, app.MakeTitle(), 'FontSize', 18);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.CovidData=covid_data;
            app.GetStates();
            app.LabelDates();
            app.GenerateData(); 
            app.DefaultSettings;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.SelectedCountry = app.CountryListBox.Value;
            app.StateorRegionListBox.Items = [ {'All'}, ...
                                               findobj([app.ListOfCountries{:}], 'Name', app.SelectedCountry).ListOfStateNames ];
            app.SelectedState = 'All';
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.SelectedState = app.StateorRegionListBox.Value;
            app.PlotData();
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web("https://github.com/HARIPRAKASH1805");
        end

        % Value changed function: DefaultSettingsButton
        function DefaultSettingsButtonValueChanged(app, event)
            app.DefaultSettings();
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.EditField.Value = '';
            app.DefaultSettings();
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            uisetcolor(app.Covid19tracerUIFigure,'Select UI Figure Color');
        end

        % Button pushed function: DataSourceButton
        function DataSourceButtonPushed(app, event)
            web("https://coronavirus.jhu.edu/map.html");
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData();            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.PlotData();            
        end

        % Value changed function: AveragednumberofdaysSlider
        function AveragednumberofdaysSliderValueChanged(app, event)
             % determine which discrete option the current value is closest to
            [~,minIdx] = min(abs(app.AveragednumberofdaysSlider.Value - app.AveragednumberofdaysSlider.MajorTicks(:)));
            % move the slider to that option
            app.AveragednumberofdaysSlider.Value = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % override the selected value if you plan on using it within this function
            app.AverageOfDay = app.AveragednumberofdaysSlider.MajorTicks(minIdx);
            % plot data
            app.PlotData();
            
        end

        % Value changing function: AveragednumberofdaysSlider
        function AveragednumberofdaysSliderValueChanging(app, event)
            
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            app.CountryorStateorRegionToFind = event.Value;
            if (app.CountryorStateorRegionToFind == "")
                app.DefaultSettings();
            else
                Countries = [ {'Global'}; app.ListOfCountryNames ];
                CountryMatched = startsWith(lower(string(Countries)), lower(app.CountryorStateorRegionToFind));
                if (sum(CountryMatched) == 0)
                    StateorRegionMatched = isKey(app.StateMap, app.CountryorStateorRegionToFind);
                    if (~StateorRegionMatched)
                        app.EditField.BackgroundColor = 'red';
                        app.EditField.FontColor = 'black';
                        app.CountryListBox.Items = "No country or state/region found.";
                        app.StateorRegionListBox.Items = "";
                        cla(app.UIAxes, 'reset');
                    else
                        app.EditField.BackgroundColor = 'green';
                        app.EditField.FontColor = '#05014a';
                        app.CountryListBox.Items = {app.StateMap(app.CountryorStateorRegionToFind)};
                        app.SelectedCountry = app.StateMap(app.CountryorStateorRegionToFind);
                        app.StateorRegionListBox.Items = [ {'All'}, ...
                                                           findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).ListOfStateNames ];
                        app.SelectedCountry = app.CountryorStateorRegionToFind;
                        app.StateorRegionListBox.Value = app.StateSelected;
                        scroll(app.StateorRegionListBox, app.StateorRegionListBox.Value);
                        app.PlotData();
                    end
                else
                    app.EditField.BackgroundColor = 'green';
                    app.EditField.FontColor = '#05014a';
                    FilteredValues = Countries(CountryMatched);
                    app.CountryListBox.Items = FilteredValues;
                    app.SelectedCountry = app.CountryListBox.Items{1};
                    app.StateorRegionListBox.Items = [ {'All'}, ...
                                                       findobj([app.ListOfCountries{:}], 'Name', app.SelectedCountry).ListOfStateNames ];
                    app.SelectedState = 'All';
                    app.PlotData();
                end
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.EditField.Value = '';
            app.DefaultSettings();
        end

        % Value changed function: FromDatePicker
        function FromDatePickerValueChanged(app, event)
            app.StartDate = datenum(app.FromDatePicker.Value);
            if (app.StartDate >= app.EndDate)
                app.EndDate = min([ app.StartDate + 1, datenum(app.ToDatePicker.Limits(2)) ]);
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
                app.StartDate = app.EndDate - 1;
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
            end
            app.PlotData();
        end

        % Value changed function: ToDatePicker
        function ToDatePickerValueChanged(app, event)
            app.EndDate = datenum(app.ToDatePicker.Value);
            if (app.EndDate <= app.StartDate)
                app.StartDate = max([ app.EndDate - 1, datenum(app.FromDatePicker.Limits(1)) ]);
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
                app.EndDate = app.StartDate + 1;
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
            end
            app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19tracerUIFigure = uifigure('Visible', 'off');
            app.Covid19tracerUIFigure.Name = 'Covid19 tracer';
            app.Toolbar = uitoolbar(app.Covid19tracerUIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'paint.png';
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'hub.png';
            app.UIAxes = uiaxes(app.Covid19tracerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19tracerUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragednumberofdaysSliderLabel = uilabel(app.Covid19tracerUIFigure);
            app.AveragednumberofdaysSliderLabel.Text = 'Averaged number of days';
            app.AveragednumberofdaysSlider = uislider(app.Covid19tracerUIFigure);
            app.AveragednumberofdaysSlider.Limits = [1 15];
            app.AveragednumberofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragednumberofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragednumberofdaysSliderValueChanged, true);
            app.AveragednumberofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragednumberofdaysSliderValueChanging, true);
            app.AveragednumberofdaysSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.Covid19tracerUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryListBoxLabel = uilabel(app.Covid19tracerUIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.Covid19tracerUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.Covid19tracerUIFigure);
            app.StateorRegionLabel.Text = '  State or Region: ';
            app.StateorRegionListBox = uilistbox(app.Covid19tracerUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DefaultSettingsButton = uibutton(app.Covid19tracerUIFigure, 'state');
            app.DefaultSettingsButton.ValueChangedFcn = createCallbackFcn(app, @DefaultSettingsButtonValueChanged, true);
            app.DefaultSettingsButton.Text = 'Default Settings';
            app.FromDatePickerLabel = uilabel(app.Covid19tracerUIFigure);
            app.FromDatePickerLabel.Text = 'From';
            app.FromDatePicker = uidatepicker(app.Covid19tracerUIFigure);
            app.FromDatePicker.ValueChangedFcn = createCallbackFcn(app, @FromDatePickerValueChanged, true);
            app.FromDatePicker.Value = datetime([2020 1 22]);
            app.ToDatePickerLabel = uilabel(app.Covid19tracerUIFigure);
            app.ToDatePickerLabel.Text = 'To';
            app.ToDatePicker = uidatepicker(app.Covid19tracerUIFigure);
            app.ToDatePicker.ValueChangedFcn = createCallbackFcn(app, @ToDatePickerValueChanged, true);
            app.ToDatePicker.Value = datetime([2021 1 30]);
            app.EditField = uieditfield(app.Covid19tracerUIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField.Placeholder = 'Enter Country or State / Region name';
            app.ClearButton = uibutton(app.Covid19tracerUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.Covid19tracerUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'clear.png';
            app.DataSourceButton = uibutton(app.Covid19tracerUIFigure, 'push');
            app.DataSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DataSourceButtonPushed, true);
            app.DataSourceButton.Text = 'Data Source';
            % Show the figure after all components are created
            app.Covid19tracerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19tracerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19tracerUIFigure)
        end
    end
end